classdef RobotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KUKAHOMEButton              matlab.ui.control.Button
        q6Spinner_2                 matlab.ui.control.Spinner
        q6Spinner_2Label            matlab.ui.control.Label
        q5Spinner_2                 matlab.ui.control.Spinner
        q5Spinner_2Label            matlab.ui.control.Label
        q4Spinner_2                 matlab.ui.control.Spinner
        q4Spinner_2Label            matlab.ui.control.Label
        q3Spinner_2                 matlab.ui.control.Spinner
        q3Spinner_2Label            matlab.ui.control.Label
        q2Spinner_2                 matlab.ui.control.Spinner
        q2Spinner_2Label            matlab.ui.control.Label
        KUKARobotJointControlLabel  matlab.ui.control.Label
        q1Spinner_2                 matlab.ui.control.Spinner
        q1Spinner_2Label            matlab.ui.control.Label
        DisengageESTOPButton        matlab.ui.control.Button
        OrderFoodButton             matlab.ui.control.Button
        UR3eHOMEButton              matlab.ui.control.Button
        ESTOPStatusLamp             matlab.ui.control.Lamp
        ESTOPStatusLampLabel        matlab.ui.control.Label
        UR3eJointControlLabel       matlab.ui.control.Label
        ESTOPButton                 matlab.ui.control.StateButton
        q6Spinner                   matlab.ui.control.Spinner
        q6SpinnerLabel              matlab.ui.control.Label
        q5Spinner                   matlab.ui.control.Spinner
        q5SpinnerLabel              matlab.ui.control.Label
        q4Spinner                   matlab.ui.control.Spinner
        q4SpinnerLabel              matlab.ui.control.Label
        q3Spinner                   matlab.ui.control.Spinner
        q3SpinnerLabel              matlab.ui.control.Label
        q2Spinner                   matlab.ui.control.Spinner
        q2SpinnerLabel              matlab.ui.control.Label
        q1Spinner                   matlab.ui.control.Spinner
        q1SpinnerLabel              matlab.ui.control.Label
        YumchaBotGUILabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        robot 
        kuka
        q
        env
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis equal
           
            app.env = Environment();  % Instantiate the Environment class
            app.env.loadEnvironment();  % Load the environment
            app.robot = app.env.ur3;
            app.kuka = app.env.kuka;
            app.q = zeros(1, 6); % Initial joint angles
            
            % Animate the robot at home position
            app.robot.model.animate(app.q); 
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q1Spinner
        function q1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [deg2rad(app.q1Spinner.Value), qcurrentPos(1,2),qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q2Spinner
        function q2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), deg2rad(app.q2Spinner.Value), qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q3Spinner
        function q3SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), deg2rad(app.q3Spinner.Value), qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q4Spinner
        function q4SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), deg2rad(app.q4Spinner.Value), qcurrentPos(1,5),qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q5Spinner
        function q5SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), deg2rad(app.q5Spinner.Value), qcurrentPos(1,6)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changing function: q6Spinner
        function q6SpinnerValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.robot.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), qcurrentPos(1,5), deg2rad(app.q6Spinner.Value)];
            app.robot.model.animate(app.q);
            % plotRobotOnAxes(app);
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
           app.ESTOPStatusLamp.Color = 'r'; 
               assignin("base", "ESTOP", app.DisengageESTOPButton.Value)
            
        end

        % Button pushed function: UR3eHOMEButton
        function UR3eHOMEButtonPushed(app, event)
            if app.ESTOPButton.Value == 0
                qcurrentPos = app.robot.model.getpos();
                qHome = zeros(1,6); 
                qtrajHome = jtraj(qcurrentPos, qHome, 50);

                for i = 1:50
                    app.robot.model.animate(qtrajHome(i,:));
                    pause(0.01);
                end
                
            end    
        end

        % Button pushed function: DisengageESTOPButton
        function DisengageESTOPButtonPushed(app, event)
            app.ESTOPStatusLamp.Color = 'g';
            app.DisengageESTOPButton.Value = 0;
        end

        % Value changing function: q1Spinner_2
        function q1Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [deg2rad(app.q1Spinner_2.Value), qcurrentPos(1,2),qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q2Spinner_2
        function q2Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [qcurrentPos(1,1), deg2rad(app.q2Spinner_2.Value), qcurrentPos(1,3),qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q3Spinner_2
        function q3Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), deg2rad(app.q3Spinner_2.Value), qcurrentPos(1,4),qcurrentPos(1,5),qcurrentPos(1,6)];
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q4Spinner_2
        function q4Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), deg2rad(app.q4Spinner_2.Value), qcurrentPos(1,5),qcurrentPos(1,6)];
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q5Spinner_2
        function q5Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), deg2rad(app.q5Spinner_2.Value), qcurrentPos(1,6)];
            app.kuka.model.animate(app.q);
        end

        % Value changing function: q6Spinner_2
        function q6Spinner_2ValueChanging(app, event)
            changingValue = event.Value;
            qcurrentPos = app.kuka.model.getpos();
            app.q = [qcurrentPos(1,1), qcurrentPos(1,2), qcurrentPos(1,3), qcurrentPos(1,4), qcurrentPos(1,5), deg2rad(app.q6Spinner_2.Value)];
            app.kuka.model.animate(app.q);
        end

        % Button pushed function: KUKAHOMEButton
        function KUKAHOMEButtonPushed(app, event)
            if app.ESTOPButton.Value == 0
                qcurrentPos = app.kuka.model.getpos();
                qHome = zeros(1,6); 
                qtrajHome = jtraj(qcurrentPos, qHome, 50);

                for i = 1:50
                    app.kuka.model.animate(qtrajHome(i,:));
                    pause(0.01);
                end

                app.q1Spinner_2.Value = 0;
                app.q2Spinner_2.Value = 0;
                app.q3Spinner_2.Value = 0;
                app.q4Spinner_2.Value = 0;
                app.q5Spinner_2.Value = 0;
                app.q6Spinner_2.Value = 0;
                
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YumchaBotGUILabel = uilabel(app.UIFigure);
            app.YumchaBotGUILabel.Text = 'YumchaBot GUI';
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.ValueChangingFcn = createCallbackFcn(app, @q1SpinnerValueChanging, true);
            app.q1Spinner.Limits = [-90 90];
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.ValueChangingFcn = createCallbackFcn(app, @q2SpinnerValueChanging, true);
            app.q2Spinner.Limits = [-90 90];
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.ValueChangingFcn = createCallbackFcn(app, @q3SpinnerValueChanging, true);
            app.q3Spinner.Limits = [-90 90];
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.ValueChangingFcn = createCallbackFcn(app, @q4SpinnerValueChanging, true);
            app.q4Spinner.Limits = [-90 90];
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.ValueChangingFcn = createCallbackFcn(app, @q5SpinnerValueChanging, true);
            app.q5Spinner.Limits = [-90 90];
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.ValueChangingFcn = createCallbackFcn(app, @q6SpinnerValueChanging, true);
            app.q6Spinner.Limits = [-90 90];
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.UR3eJointControlLabel = uilabel(app.UIFigure);
            app.UR3eJointControlLabel.Text = 'UR3e Joint Control';
            app.ESTOPStatusLampLabel = uilabel(app.UIFigure);
            app.ESTOPStatusLampLabel.Text = 'ESTOP Status: ';
            app.ESTOPStatusLamp = uilamp(app.UIFigure);
            app.UR3eHOMEButton = uibutton(app.UIFigure, 'push');
            app.UR3eHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @UR3eHOMEButtonPushed, true);
            app.UR3eHOMEButton.Text = 'UR3e HOME';
            app.OrderFoodButton = uibutton(app.UIFigure, 'push');
            app.OrderFoodButton.Text = 'Order Food';
            app.DisengageESTOPButton = uibutton(app.UIFigure, 'push');
            app.DisengageESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DisengageESTOPButtonPushed, true);
            app.DisengageESTOPButton.Text = 'Disengage ESTOP';
            app.q1Spinner_2Label = uilabel(app.UIFigure);
            app.q1Spinner_2Label.Text = 'q1';
            app.q1Spinner_2 = uispinner(app.UIFigure);
            app.q1Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q1Spinner_2ValueChanging, true);
            app.KUKARobotJointControlLabel = uilabel(app.UIFigure);
            app.KUKARobotJointControlLabel.Text = 'KUKA Robot Joint Control';
            app.q2Spinner_2Label = uilabel(app.UIFigure);
            app.q2Spinner_2Label.Text = 'q2';
            app.q2Spinner_2 = uispinner(app.UIFigure);
            app.q2Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q2Spinner_2ValueChanging, true);
            app.q3Spinner_2Label = uilabel(app.UIFigure);
            app.q3Spinner_2Label.Text = 'q3';
            app.q3Spinner_2 = uispinner(app.UIFigure);
            app.q3Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q3Spinner_2ValueChanging, true);
            app.q4Spinner_2Label = uilabel(app.UIFigure);
            app.q4Spinner_2Label.Text = 'q4';
            app.q4Spinner_2 = uispinner(app.UIFigure);
            app.q4Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q4Spinner_2ValueChanging, true);
            app.q5Spinner_2Label = uilabel(app.UIFigure);
            app.q5Spinner_2Label.Text = 'q5';
            app.q5Spinner_2 = uispinner(app.UIFigure);
            app.q5Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q5Spinner_2ValueChanging, true);
            app.q6Spinner_2Label = uilabel(app.UIFigure);
            app.q6Spinner_2Label.Text = 'q6';
            app.q6Spinner_2 = uispinner(app.UIFigure);
            app.q6Spinner_2.ValueChangingFcn = createCallbackFcn(app, @q6Spinner_2ValueChanging, true);
            app.KUKAHOMEButton = uibutton(app.UIFigure, 'push');
            app.KUKAHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @KUKAHOMEButtonPushed, true);
            app.KUKAHOMEButton.Text = 'KUKA HOME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end